classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Easy_Assessment        matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        savePathmenu           matlab.ui.container.Menu
        savePathmenu_restore   matlab.ui.container.Menu
        progTableoptmenu       matlab.ui.container.Menu
        progtable_changemenu   matlab.ui.container.Menu
        progtable_restoremenu  matlab.ui.container.Menu
        excelMenu              matlab.ui.container.Menu
        getExcelmenu           matlab.ui.container.Menu
        changeExcelmenu        matlab.ui.container.Menu
        restoreExcelmenu       matlab.ui.container.Menu
        Exitmenu               matlab.ui.container.Menu
        b_calculate_input      matlab.ui.control.Button
        logPanel               matlab.ui.control.TextArea
        Panel                  matlab.ui.container.Panel
        t_lim_prec             matlab.ui.control.NumericEditField
        t_lim_prec_label       matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        fileDate_bar           matlab.ui.control.EditField
        AllTabs                matlab.ui.container.TabGroup
        tabAsNight             matlab.ui.container.Tab
        dataAssessNight        matlab.ui.control.Table
        tabAsDay               matlab.ui.container.Tab
        dataAssessDay          matlab.ui.control.Table
        tabFullday             matlab.ui.container.Tab
        dataFullDay            matlab.ui.control.Table
        tabTerritory           matlab.ui.container.Tab
        dataTerritory          matlab.ui.control.Table
        tabCalc                matlab.ui.container.Tab
        dataCalc               matlab.ui.control.Table
        tabCalc_p              matlab.ui.container.Tab
        dataCalc_p             matlab.ui.control.Table
        tabCalc_phen           matlab.ui.container.Tab
        dataCalc_phen          matlab.ui.control.Table
        b_calculate_inprog     matlab.ui.control.Button
        b_save                 matlab.ui.control.Button
        b_clear                matlab.ui.control.Button
        menu_b                 matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        defPath_pub
        day_str_as
        night_str_as
        fullday_str

        fact_t_nPub;
     
        fullday_assess_pub
        territorial_assess_pub
        
        normData_pub
        fileDate_pub
        
        calc_tables
        % =============== crutch for table ==============
        vitebsk_t_n = 0;
        grodno_t_n = 0;
        minsk_t_n = 0;
        mogilev_t_n = 0;
        brest_t_n = 0;
        gomel_t_n = 0;
        
        vitebsk_p_n = 0;
        grodno_p_n = 0;
        minsk_p_n = 0;
        mogilev_p_n = 0;
        brest_p_n = 0;
        gomel_p_n = 0;
        
        %===========================
        
        vitebsk_t_d = 0;
        grodno_t_d = 0;
        minsk_t_d = 0;
        mogilev_t_d = 0;
        brest_t_d = 0;
        gomel_t_d = 0;
        
        vitebsk_p_d = 0;
        grodno_p_d = 0;
        minsk_p_d = 0;
        mogilev_p_d = 0;
        brest_p_d = 0;
        gomel_p_d = 0;
    end
    
    properties (Access = private)
        editor_window
    end
    
    methods (Access = private)
        
        function cellEdit_crutch(app, range, idx, newData)
            for n = range(1):range(2)
                if ~isempty(app.dataAssessNight.Data{n,idx - 2})
                    app.dataAssessNight.Data{n, idx} = newData;
                end
            end
        end
    
        function cellEdit_crutchd(app, range, idx, newData)
            for n = range(1):range(2)
                if ~isempty(app.dataAssessDay.Data{n,idx - 2})
                    app.dataAssessDay.Data{n, idx} = newData;
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logPanel.Value = 'Запуск программы';
            d = uiprogressdlg(app.Easy_Assessment,...
                'Message','Инициализация программы','Indeterminate','on');
            
            load('night_assessment.mat');
            load('day_assessment.mat');
            load('assessment_fullday.mat');
            load('territorial_assessment.mat');
            load('calc_table.mat');
            load('normData.mat');
            load('calc_table_p.mat');
            load('calc_table_phen.mat');
            
            app.day_str_as = day_assessment;
            app.night_str_as = night_assessment;
            app.fullday_str = assessment_fullday;
            app.territorial_assess_pub = territorial_assessment;
            app.normData_pub = normData;
            app.calc_tables.calc_table_pub = calc_table;
            app.calc_tables.calc_table_p_pub = calc_table_p;
            app.calc_tables.calc_table_phen_pub = calc_table_phen;
            
            header = night_assessment(3,1:end);
            header(3:8) = night_assessment(2,3:8);
            header(33) = night_assessment(2,33);
            header(34) = night_assessment(1,34);
            
            app.dataAssessNight.RowName = night_assessment(1:end,2);
            app.dataAssessNight.ColumnName = header;
            app.dataAssessNight.Data = night_assessment;
            
            app.dataAssessDay.RowName = day_assessment(1:end,2);
            app.dataAssessDay.ColumnName = header;
            app.dataAssessDay.Data = day_assessment;
            
            app.dataFullDay.Data = assessment_fullday;
            app.dataTerritory.Data = territorial_assessment;
            
            app.dataCalc.RowName = calc_table(1:end,2);
            app.dataCalc.ColumnName = calc_table(2,1:end);
            app.dataCalc.Data = calc_table;
            
            app.dataCalc_p.RowName = calc_table_p(1:end,2);
            app.dataCalc_p.ColumnName = calc_table_p(2,1:end);
            app.dataCalc_p.Data = calc_table_p;
            
            app.dataCalc_phen.RowName = calc_table_phen(1:end,2);
            app.dataCalc_phen.ColumnName = calc_table_phen(2,1:end);
            app.dataCalc_phen.Data = calc_table_phen;
            
            tableWidth = zeros(1,length(night_assessment(1,:)));
            tableWidth(1:end) = 90;
            tableWidth(9) = 120;
            tableWidth(1) = 105;
            tableWidth(end) = 180;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataAssessNight,'ColumnWidth', tableWidth);
            set(app.dataAssessDay,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(territorial_assessment(1,:)));
            tableWidth(1:end) = 110;
            tableWidth(1) = 200;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataTerritory,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(calc_table(1,:)));
            tableWidth(1:end) = 120;
            tableWidth(4) = 110;
            tableWidth(11) = 110;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataCalc,'ColumnWidth', tableWidth);
            set(app.dataCalc_p,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(calc_table(1,:)));
            tableWidth(1:end) = 120;
            tableWidth(3) = 160;
            tableWidth(4) = 110;
            tableWidth(11) = 110;
            tableWidth(15) = 180;
            tableWidth(28) = 160;
            tableWidth(40) = 180;
            tableWidth = num2cell(tableWidth);
            set(app.dataCalc_phen,'ColumnWidth', tableWidth);
            
            try
                config_id = fopen([pwd, '\bin\.cfg'], 'r');
                app.defPath_pub = textscan(config_id, '%s');
                fclose(config_id);
                if ~isempty(app.defPath_pub{1})
                    temp = app.defPath_pub{1};
                    app.defPath_pub = temp{1};
                end
            catch
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при открытии файла конфигурации'];
            end
            
            if length(app.defPath_pub) < 2
                app.logPanel.Value = [app.logPanel.Value; 'Путь по умолчанию не задан'];
            else
                app.logPanel.Value = [app.logPanel.Value; strcat('Путь по умолчанию: ',app.defPath_pub)];
            end
            pause(7);
            d.close();
        end

        % Close request function: Easy_Assessment
        function Easy_AssessmentCloseRequest(app, event)
            delete(app.editor_window);
            delete(app)
        end

        % Button pushed function: b_calculate_input
        function b_calculate_inputButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Загрузка...','Indeterminate','on');
            [file,path] = uigetfile('*.xlsx', 'Выберите файл');
            if length(file) > 1
                fPath = [path,file];
                try
                    data = readtable(fPath);
                    date = data.Properties.VariableDescriptions(1);
                    expression = '(?<day>\d+).(?<month>\d+).(?<year>\d+)';
                    date = regexp(date{1},expression,'names');
                    app.fileDate_pub = [str2double(date(2).('day')), str2double(date(2).('month')), str2double(date(2).('year'))];
                    if app.fileDate_pub(1) < 10
                        temp_string = strcat('0', num2str(app.fileDate_pub(1)));
                        if app.fileDate_pub(2) < 10
                            temp_string = strcat(temp_string, '.0');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(2)));
                            temp_string = strcat(temp_string, '.');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(3)));
                        else
                            temp_string = strcat(temp_string, '.');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(2)));
                            temp_string = strcat(temp_string, '.');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(3)));
                        end
                    else
                        temp_string = num2str(app.fileDate_pub(1));
                        if app.fileDate_pub(2) < 10
                            temp_string = strcat(temp_string, '.0');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(2)));
                            temp_string = strcat(temp_string, '.');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(3)));
                        else
                            temp_string = strcat(temp_string, '.');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(2)));
                            temp_string = strcat(temp_string, '.');
                            temp_string = strcat(temp_string, num2str(app.fileDate_pub(3)));
                        end
                    end
                    app.fileDate_bar.Value = temp_string;
                    clear temp_string;
                    
                    
                    app.logPanel.Value = [app.logPanel.Value; 'Файл загружен'];
                catch exeption
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка работы Microsoft Excel:...'];
                    app.logPanel.Value = [app.logPanel.Value; 'не удалось загрузить входной файл'];
                    app.logPanel.Value = [app.logPanel.Value; exeption.message];
                end
                
            else
                uialert(app.Easy_Assessment, 'Ошибка при загрузке файла', 'Ошибка', 'Icon','error');
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при загрузке файла'];
            end
            
            try
                [app.dataAssessNight.Data, app.dataAssessDay.Data, app.dataFullDay.Data, app.dataTerritory.Data, ...
                    app.dataCalc.Data, app.dataCalc_p.Data, app.dataCalc_phen.Data, app.fact_t_nPub] = ...
                    func_loadButton(app.dataAssessNight.Data, app.dataAssessDay.Data, app.dataFullDay.Data, app.dataTerritory.Data,...
                    app.dataCalc.Data, app.dataCalc_p.Data, app.dataCalc_phen.Data, app.normData_pub, app.t_lim_prec.Value, app.fileDate_pub, data);
                
                header = app.dataAssessNight.Data(3,1:end);
                header(3:8) = app.dataAssessNight.Data(2,3:8);
                header(33) = app.dataAssessNight.Data(2,33);
                header(34) = app.dataAssessNight.Data(1,34);
                
                app.dataAssessNight.RowName = app.dataAssessNight.Data(1:end,2);
                app.dataAssessNight.ColumnName = header;
                
                app.dataAssessDay.RowName = app.dataAssessDay.Data(1:end,2);
                app.dataAssessDay.ColumnName = header;
                
                
                app.dataCalc.RowName = app.dataCalc.Data(1:end,2);
                app.dataCalc.ColumnName = app.dataCalc.Data(2,1:end);
                
                app.dataCalc_p.RowName = app.dataCalc_p.Data(1:end,2);
                app.dataCalc_p.ColumnName = app.dataCalc_p.Data(2,1:end);
                
                app.dataCalc_phen.RowName = app.dataCalc_phen.Data(1:end,2);
                app.dataCalc_phen.ColumnName = app.dataCalc_phen.Data(2,1:end);
                
                app.b_save.Enable = 1;
            catch e
                uialert(app.Easy_Assessment, 'Ошибка расчета', 'Ошибка', 'Icon','error');
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка расчета'];
                app.logPanel.Value = [app.logPanel.Value; e.message];
            end
            
            d.close();
        end

        % Menu selected function: savePathmenu
        function b_savePathButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,...
                'Message','Получение пути','Indeterminate','on');
            dir = uigetdir(pwd, 'Выберите путь по умолчанию');
            if length(dir) > 2
                try
                    user_conf_id = fopen([pwd, '\bin\.cfg'], 'w');
                    fprintf(user_conf_id, '%s' , dir);
                    fclose(user_conf_id);
                    d.close();
                    uialert(app.Easy_Assessment, 'Путь по умолчанию для выходного файли изменен', 'Информация', 'Icon','info');
                    app.logPanel.Value = [app.logPanel.Value; 'Путь по умолчанию для выходного файли изменен'];
                    app.logPanel.Value = [app.logPanel.Value; strcat('Новый путь: ', dir)];
                catch exeption
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка при попытке задать путь по умолчанию'];
                    app.logPanel.Value = [app.logPanel.Value; exeption.message];
                end
            else
                app.logPanel.Value = [app.logPanel.Value; 'Операция отменена'];
            end
            
            try
                config_id = fopen([pwd, '\bin\.cfg'], 'r');
                app.defPath_pub = textscan(config_id, '%s');
                fclose(config_id);
                if ~isempty(app.defPath_pub{1})
                    temp = app.defPath_pub{1};
                    app.defPath_pub = temp{1};
                end
            catch exeption
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при открытии файла конфигурации'];
                app.logPanel.Value = [app.logPanel.Value; exeption.message];
            end
            d.close();
        end

        % Menu selected function: Exitmenu
        function b_exitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: b_calculate_inprog
        function b_calculate_inprogButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Загрузка...','Indeterminate','on');
            if isempty(app.fileDate_pub)
                uialert(app.Easy_Assessment, 'Укажите дату для текущего расчета', '', 'Icon','info');
            else
                try
                    [app.dataAssessNight.Data, app.dataAssessDay.Data, app.dataFullDay.Data, app.dataTerritory.Data, ...
                        app.dataCalc.Data, app.dataCalc_p.Data, app.dataCalc_phen.Data, app.fact_t_nPub] = ...
                        func_loadButton(app.dataAssessNight.Data, app.dataAssessDay.Data, app.dataFullDay.Data, app.dataTerritory.Data,...
                        app.dataCalc.Data, app.dataCalc_p.Data, app.dataCalc_phen.Data, app.normData_pub, app.t_lim_prec.Value, app.fileDate_pub);
                    
                    header = app.dataAssessNight.Data(3,1:end);
                    header(3:8) = app.dataAssessNight.Data(2,3:8);
                    header(33) = app.dataAssessNight.Data(2,33);
                    header(34) = app.dataAssessNight.Data(1,34);
                    
                    app.dataAssessNight.RowName = app.dataAssessNight.Data(1:end,2);
                    app.dataAssessNight.ColumnName = header;
                    
                    app.dataAssessDay.RowName = app.dataAssessDay.Data(1:end,2);
                    app.dataAssessDay.ColumnName = header;
                    
                    
                    app.dataCalc.RowName = app.dataCalc.Data(1:end,2);
                    app.dataCalc.ColumnName = app.dataCalc.Data(2,1:end);
                    
                    app.dataCalc_p.RowName = app.dataCalc_p.Data(1:end,2);
                    app.dataCalc_p.ColumnName = app.dataCalc_p.Data(2,1:end);
                    
                    app.dataCalc_phen.RowName = app.dataCalc_phen.Data(1:end,2);
                    app.dataCalc_phen.ColumnName = app.dataCalc_phen.Data(2,1:end);
                    
                    app.logPanel.Value = [app.logPanel.Value; 'Таблица выведена'];
                    
                    app.b_save.Enable = 1;
                catch e
                    uialert(app.Easy_Assessment, 'Ошибка расчета', 'Ошибка', 'Icon','error');
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка расчета'];
                    app.logPanel.Value = [app.logPanel.Value; e.message];
                end     
            end
            d.close();
        end

        % Button pushed function: b_save
        function b_saveButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Сохранение...','Indeterminate','on');
            % ============== night_output =============================================
            % =========================================================================
            if ~isempty(app.fact_t_nPub)
                if length(app.defPath_pub) < 2
                    out_dir = uigetdir(pwd, 'Выберите путь для сохранения файла');
                else
                    out_dir = app.defPath_pub;
                end
                if length(out_dir) > 2
                    try
                        out_dir = [out_dir, '\оценка_прогнозирования_', app.fileDate_bar.Value, '.xlsx'];
                        copyfile('bin\оценка_прогнозирования.xlsx', out_dir);
                    catch exeption
                        app.logPanel.Value = [app.logPanel.Value; 'Ошибка копирования образца выходного файла'];
                        app.logPanel.Value = [app.logPanel.Value; exeption.message];
                    end                    
                    
                    try
                        % ============== date_output ==============================================
                        % =========================================================================
                        writetable(cell2table({['Прогноз на ночь с 900 до 2100 (', app.fileDate_bar.Value, ')']}),...
                            out_dir, 'Sheet', 1, 'Range', 'A1', 'WriteVariableNames', 0);
                        writetable(cell2table({['Прогноз на день с 900 до 2100 (', app.fileDate_bar.Value, ')']}),...
                            out_dir, 'Sheet', 2, 'Range', 'A1', 'WriteVariableNames', 0);
                        
                        % ============== night_output =============================================
                        % =========================================================================
                        writetable(cell2table(app.dataAssessNight.Data),...
                            out_dir, 'Sheet', 1, 'Range', 'A4:AH69', 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataAssessNight.Data(4:end,2)),...
%                             out_dir, 'Sheet', 1, 'Range', 'B5:B69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(2,10:20)),...
%                             out_dir, 'Sheet', 1, 'Range', 'J4:T4', 'WriteVariableNames', 0);                        
%                         writetable(cell2table(app.dataAssessNight.Data(2,10:20)),...
%                             out_dir, 'Sheet', 1, 'Range', 'V4:AF4', 'WriteVariableNames', 0);
% 
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 3)),...
%                             out_dir, 'Sheet', 1, 'Range', 'C5:C69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 4)),...
%                             out_dir, 'Sheet', 1, 'Range', 'D5:D69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 5)),...
%                             out_dir, 'Sheet', 1, 'Range', 'E5:E69', 'WriteVariableNames', 0);
%                         
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 6)),...
%                             out_dir, 'Sheet', 1, 'Range', 'F5:F69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 7)),...
%                             out_dir, 'Sheet', 1, 'Range', 'G5:G69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 8)),...
%                             out_dir, 'Sheet', 1, 'Range', 'H5:H69', 'WriteVariableNames', 0);                       
%                         
%                         
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 9)),...
%                             out_dir, 'Sheet', 1, 'Range', 'I5:I69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 21)),...
%                             out_dir, 'Sheet', 1, 'Range', 'U5:U69', 'WriteVariableNames', 0);
%                         clear n temp0 temp1
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 10:20)),...
%                             out_dir, 'Sheet', 1, 'Range', 'J5:T69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 22:32)),...
%                             out_dir, 'Sheet', 1, 'Range', 'V5:AF69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 33)),...
%                             out_dir, 'Sheet', 1, 'Range', 'AG5:AG69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessNight.Data(4:end, 34)),...
%                             out_dir, 'Sheet', 1, 'Range', 'AH5:AH69', 'WriteVariableNames', 0);
                        
                        % ============= day_output ================================================
                        % =========================================================================
                        writetable(cell2table(app.dataAssessDay.Data),...
                            out_dir, 'Sheet', 2, 'Range', 'A4:AH69', 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataAssessDay.Data(4:end,2)),...
%                             out_dir, 'Sheet', 2, 'Range', 'B5:B69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(2,10:20)),...
%                             out_dir, 'Sheet', 2, 'Range', 'J4:T4', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(2,10:20)),...
%                             out_dir, 'Sheet', 2, 'Range', 'V4:AF4', 'WriteVariableNames', 0);
%                         
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 3)),...
%                             out_dir, 'Sheet', 2, 'Range', 'C5:C69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 4)),...
%                             out_dir, 'Sheet', 2, 'Range', 'D5:D69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 5)),...
%                             out_dir, 'Sheet', 2, 'Range', 'E5:E69', 'WriteVariableNames', 0);
%                         
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 6)),...
%                             out_dir, 'Sheet', 2, 'Range', 'F5:F69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 7)),...
%                             out_dir, 'Sheet', 2, 'Range', 'G5:G69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 8)),...
%                             out_dir, 'Sheet', 2, 'Range', 'H5:H69', 'WriteVariableNames', 0);
%                         
%                         
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 9)),...
%                             out_dir, 'Sheet', 2, 'Range', 'I5:I69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 21)),...
%                             out_dir, 'Sheet', 2, 'Range', 'U5:U69', 'WriteVariableNames', 0);
%                         clear n temp0 temp1
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 10:20)),...
%                             out_dir, 'Sheet', 2, 'Range', 'J5:T69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 22:32)),...
%                             out_dir, 'Sheet', 2, 'Range', 'V5:AF69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 33)),...
%                             out_dir, 'Sheet', 2, 'Range', 'AG5:AG69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataAssessDay.Data(4:end, 34)),...
%                             out_dir, 'Sheet', 2, 'Range', 'AH5:AH69', 'WriteVariableNames', 0);
                        
                        % ============= fullday_out ===============================================
                        % =========================================================================
                        writetable(cell2table(app.dataFullDay.Data),...
                            out_dir, 'Sheet', 3, 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataFullDay.Data(3:end, 2)),...
%                             out_dir, 'Sheet', 3, 'Range', 'B5:B69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataFullDay.Data(3:end, 3)),...
%                             out_dir, 'Sheet', 3, 'Range', 'C5:C69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataFullDay.Data(3:end, 4)),...
%                             out_dir, 'Sheet', 3, 'Range', 'D5:D69', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataFullDay.Data(3:end, 5)),...
%                             out_dir, 'Sheet', 3, 'Range', 'E5:E69', 'WriteVariableNames', 0);
                        
                        % ============= territorial_out ===========================================
                        % =========================================================================
                        writetable(cell2table(app.dataTerritory.Data),...
                            out_dir, 'Sheet', 4, 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataTerritory.Data(3, 3:end)),...
%                             out_dir, 'Sheet', 4, 'Range', 'B4:M4', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataTerritory.Data(4, 3:end)),...
%                             out_dir, 'Sheet', 4, 'Range', 'B8:M8', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataTerritory.Data(5, 3:end)),...
%                             out_dir, 'Sheet', 4, 'Range', 'B10:M10', 'WriteVariableNames', 0);
                        
                        % ============= add_temp_assess ===========================================
                        % =========================================================================
                        writetable(cell2table(app.dataCalc.Data),...
                            out_dir, 'Sheet', 5, 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataCalc.Data(3:end-7, 2:end)),...
%                             out_dir, 'Sheet', 5, 'Range', 'B4:X68', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc.Data(71:73, 3:10)),...
%                             out_dir, 'Sheet', 5, 'Range', 'C73:J75', 'WriteVariableNames', 0);
                        
                        % ============= add_prec_assess ===========================================
                        % =========================================================================
                        writetable(cell2table(app.dataCalc_p.Data),...
                            out_dir, 'Sheet', 6, 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataCalc_p.Data(3:end-13, 2:end)),...
%                             out_dir, 'Sheet', 6, 'Range', 'B4:H68', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_p.Data(78:80, 3:8)),...
%                             out_dir, 'Sheet', 6, 'Range', 'C73:J75', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_p.Data(71:73, 11:end)),...
%                             out_dir, 'Sheet', 6, 'Range', 'K72:M74', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_p.Data(77:79, 11:end)),...
%                             out_dir, 'Sheet', 6, 'Range', 'K78:M80', 'WriteVariableNames', 0);
                        
                        % ============= add_prec_assess ===========================================
                        % =========================================================================
                        writetable(cell2table(app.dataCalc_phen.Data),...
                            out_dir, 'Sheet', 7, 'WriteVariableNames', 0);
                        
%                         writetable(cell2table(app.dataCalc_phen.Data(3:end-13, 2:27)),...
%                             out_dir, 'Sheet', 7, 'Range', 'B4:AA68', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_phen.Data(3:end-13, 28:end)),...
%                             out_dir, 'Sheet', 7, 'Range', 'AC4:BA68', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_phen.Data(77:79, 3:10)),...
%                             out_dir, 'Sheet', 7, 'Range', 'C78:J80', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_phen.Data(71:73, 13:15)),...
%                             out_dir, 'Sheet', 7, 'Range', 'M72:O74', 'WriteVariableNames', 0);
%                         writetable(cell2table(app.dataCalc_phen.Data(77:79, 13:15)),...
%                             out_dir, 'Sheet', 7, 'Range', 'M78:O80', 'WriteVariableNames', 0);


                        uialert(app.Easy_Assessment, 'Операция завершена', 'Сохранение файла', 'Icon','info');
                        app.logPanel.Value = [app.logPanel.Value; 'Файл сохранен'];
                    catch exeption
                        app.logPanel.Value = [app.logPanel.Value; 'Ошибка работы Microsoft Excel:...'];
                        app.logPanel.Value = [app.logPanel.Value; 'не удалось сохранить выходной файл'];
                        app.logPanel.Value = [app.logPanel.Value; exeption.message];
                    end
                else
                    uialert(app.Easy_Assessment, 'Ошибка при сохранении файла', 'Ошибка', 'Icon','error');
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка при сохранении файла'];
                end
            else
                uialert(app.Easy_Assessment, 'Нету данных в таблице', 'Ошибка', 'Icon','error');
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при сохранении файла. Нет данных'];
            end
            
            d.close();
        end

        % Button pushed function: b_clear
        function b_clearButtonPushed(app, event)
            app.b_save.Enable = 0;
            
            day_assessment = app.day_str_as;
            night_assessment = app.night_str_as;
            assessment_fullday = app.fullday_str;
            territorial_assessment = app.territorial_assess_pub;
            calc_table = app.calc_tables.calc_table_pub;
            calc_table_p = app.calc_tables.calc_table_p_pub;
            calc_table_phen = app.calc_tables.calc_table_phen_pub;
            
            header = night_assessment(3,1:end);
            header(3:8) = night_assessment(2,3:8);
            header(33) = night_assessment(2,33);
            header(34) = night_assessment(1,34);
            
            app.dataAssessNight.RowName = night_assessment(1:end,2);
            app.dataAssessNight.ColumnName = header;
            app.dataAssessNight.Data = night_assessment;
            
            app.dataAssessDay.RowName = day_assessment(1:end,2);
            app.dataAssessDay.ColumnName = header;
            app.dataAssessDay.Data = day_assessment;
            
            app.dataFullDay.Data = assessment_fullday;
            app.dataTerritory.Data = territorial_assessment;
            
            app.dataCalc.RowName = calc_table(1:end,2);
            app.dataCalc.ColumnName = calc_table(2,1:end);
            app.dataCalc.Data = calc_table;
            
            app.dataCalc_p.RowName = calc_table_p(1:end,2);
            app.dataCalc_p.ColumnName = calc_table_p(2,1:end);
            app.dataCalc_p.Data = calc_table_p;
            
            app.dataCalc_phen.RowName = calc_table_phen(1:end,2);
            app.dataCalc_phen.ColumnName = calc_table_phen(2,1:end);
            app.dataCalc_phen.Data = calc_table_phen;
            
            tableWidth = zeros(1,length(night_assessment(1,:)));
            tableWidth(1:end) = 90;
            tableWidth(9) = 120;
            tableWidth(1) = 105;
            tableWidth(end) = 180;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataAssessNight,'ColumnWidth', tableWidth);
            set(app.dataAssessDay,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(territorial_assessment(1,:)));
            tableWidth(1:end) = 110;
            tableWidth(1) = 200;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataTerritory,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(calc_table(1,:)));
            tableWidth(1:end) = 120;
            tableWidth(4) = 110;
            tableWidth(11) = 110;
            tableWidth = num2cell(tableWidth);
            
            set(app.dataCalc,'ColumnWidth', tableWidth);
            set(app.dataCalc_p,'ColumnWidth', tableWidth);
            
            tableWidth = zeros(1,length(calc_table(1,:)));
            tableWidth(1:end) = 120;
            tableWidth(3) = 160;
            tableWidth(4) = 110;
            tableWidth(11) = 110;
            tableWidth(15) = 180;
            tableWidth(28) = 160;
            tableWidth(40) = 180;
            tableWidth = num2cell(tableWidth);
            set(app.dataCalc_phen,'ColumnWidth', tableWidth);
            
        end

        % Menu selected function: getExcelmenu
        function getExcelmenuMenuSelected(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Копирование...','Indeterminate','on');
            % ============== night_output =============================================
            % =========================================================================
            if length(app.defPath_pub) < 2
                out_dir = uigetdir(pwd, 'Выберите путь для сохранения файла');
            else
                out_dir = app.defPath_pub;
            end
            if length(out_dir) > 2
                try
                    copyfile('bin\образец.xlsx', out_dir);
                catch exeption
                    app.logPanel.Value = [app.logPanel.Value; 'Ошибка копирования образца входного файла'];
                    app.logPanel.Value = [app.logPanel.Value; exeption.message];
                end
            else
                uialert(app.Easy_Assessment, 'Ошибка копирования образца', 'Ошибка', 'Icon','error');
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка копирования образца'];
            end
            d.close();
        end

        % Menu selected function: changeExcelmenu
        function changeExcelmenuMenuSelected(app, event)
            s = uiconfirm(app.Easy_Assessment, 'При смене расположения ячеек или их удалении, а также смене порядка листов программа будет работать некорректно. Редактировать можно только названия станций и явлений. При этом делать это нужно зеркально не путая порядка ячеек. Хотите продолжить?',...
                'Внимание!', 'Icon','info');
            if s =='OK'
                system([pwd, '\bin\образец.xlsx']);
            end
        end

        % Menu selected function: restoreExcelmenu
        function restoreExcelmenuMenuSelected(app, event)
            try
                copyfile('source\restoreFile.xlsx', 'bin\образец.xlsx');
                app.logPanel.Value = [app.logPanel.Value; 'Образец .xlsx восстановлен'];
            catch exeption
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка восстановления образца'];
                app.logPanel.Value = [app.logPanel.Value; exeption.message];
            end
        end

        % Cell edit callback: dataAssessNight
        function dataAssessNightCellEdit(app, event)
            if event.Indices(2) == 2
                newData = event.NewData;
                app.dataAssessDay.Data{event.Indices(1),event.Indices(2)} = newData;
                app.dataAssessDay.RowName{event.Indices(1)} = newData;
                app.dataAssessNight.RowName{event.Indices(1)} = newData;
                if event.Indices(1) ~= 1
                    app.dataFullDay.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc.RowName{event.Indices(1)-1} = newData;
                    app.dataCalc_p.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc_p.RowName{event.Indices(1)-1} = newData;
                    app.dataCalc_phen.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc_phen.RowName{event.Indices(1)-1} = newData;
                else
                    app.dataFullDay.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            %             if event.Indices(2) == 6
            %                 newData = event.NewData;
            %                 app.dataDay.Data{event.Indices(1),event.Indices(2)} = newData;
            %                 app.dataAssessDay.Data{event.Indices(1)+1,event.Indices(2)} = newData;
            %                 app.dataAssessNight.Data{event.Indices(1)+1,event.Indices(2)} = newData;
            %                 app.dataFullDay.Data{event.Indices(1),event.Indices(2)} = newData;
            %             end
            
            % ========================== t_night ================================================
            if event.Indices(1) >= 4 && event.Indices(1) <= 16 && event.Indices(2) == 4
                newData = event.NewData;
                range = [4,16];
                if app.vitebsk_t_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.vitebsk_t_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.vitebsk_t_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 17 && event.Indices(1) <= 25 && event.Indices(2) == 4
                newData = event.NewData;
                range = [17,25];
                if app.grodno_t_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.grodno_t_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.grodno_t_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 26 && event.Indices(1) <= 39 && event.Indices(2) == 4
                newData = event.NewData;
                range = [26,39];
                if app.minsk_t_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.minsk_t_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.minsk_t_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 40 && event.Indices(1) <= 48 && event.Indices(2) == 4
                newData = event.NewData;
                range = [40,48];
                if app.mogilev_t_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.mogilev_t_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.mogilev_t_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 49 && event.Indices(1) <= 59 && event.Indices(2) == 4
                newData = event.NewData;
                range = [49,59];
                if app.brest_t_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.brest_t_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.brest_t_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 60 && event.Indices(1) <= 68 && event.Indices(2) == 4
                newData = event.NewData;
                range = [60,68];
                if app.gomel_t_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.gomel_t_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.gomel_t_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            % ========================== p_night ================================================
            if event.Indices(1) >= 4 && event.Indices(1) <= 16 && event.Indices(2) == 6
                newData = event.NewData;
                range = [4,16];
                if app.vitebsk_p_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.vitebsk_p_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.vitebsk_p_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 17 && event.Indices(1) <= 25 && event.Indices(2) == 6
                newData = event.NewData;
                range = [17,25];
                if app.grodno_p_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.grodno_p_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.grodno_p_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 26 && event.Indices(1) <= 39 && event.Indices(2) == 6
                newData = event.NewData;
                range = [26,39];
                if app.minsk_p_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.minsk_p_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.minsk_p_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 40 && event.Indices(1) <= 48 && event.Indices(2) == 6
                newData = event.NewData;
                range = [40,48];
                if app.mogilev_p_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.mogilev_p_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.mogilev_p_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 49 && event.Indices(1) <= 59 && event.Indices(2) == 6
                newData = event.NewData;
                range = [49,59];
                if app.brest_p_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.brest_p_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.brest_p_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 60 && event.Indices(1) <= 68 && event.Indices(2) == 6
                newData = event.NewData;
                range = [60,68];
                if app.gomel_p_n == 0
                    cellEdit_crutch(app, range, event.Indices(2), newData);
                    app.gomel_p_n = 1;
                elseif isempty(newData)
                    cellEdit_crutch(app, range, event.Indices(2),newData);
                    app.gomel_p_n = 0;
                else
                    %                     app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
        end

        % Cell edit callback: dataAssessDay
        function dataAssessDayCellEdit(app, event)
            if event.Indices(2) == 2
                newData = event.NewData;
                app.dataAssessNight.Data{event.Indices(1),event.Indices(2)} = newData;
                app.dataAssessDay.RowName{event.Indices(1)} = newData;
                app.dataAssessNight.RowName{event.Indices(1)} = newData;
                if event.Indices(1) ~= 1
                    app.dataFullDay.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc.RowName{event.Indices(1)-1} = newData;
                    app.dataCalc_p.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc_p.RowName{event.Indices(1)-1} = newData;
                    app.dataCalc_phen.Data{event.Indices(1)-1,event.Indices(2)} = newData;
                    app.dataCalc_phen.RowName{event.Indices(1)-1} = newData;
                else
                    app.dataFullDay.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            % ========================== t_day ================================================
            if event.Indices(1) >= 4 && event.Indices(1) <= 16 && event.Indices(2) == 4
                newData = event.NewData;
                range = [4,16];
                if app.vitebsk_t_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.vitebsk_t_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.vitebsk_t_d = 0;
                else
                    %                     app.dataAssessDay.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 17 && event.Indices(1) <= 25 && event.Indices(2) == 4
                newData = event.NewData;
                range = [17,25];
                if app.grodno_t_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.grodno_t_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.grodno_t_d = 0;
                else
                    %                     app.dataAssessDay.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 26 && event.Indices(1) <= 39 && event.Indices(2) == 4
                newData = event.NewData;
                range = [26,39];
                if app.minsk_t_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.minsk_t_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.minsk_t_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 40 && event.Indices(1) <= 48 && event.Indices(2) == 4
                newData = event.NewData;
                range = [40,48];
                if app.mogilev_t_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.mogilev_t_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.mogilev_t_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 49 && event.Indices(1) <= 59 && event.Indices(2) == 4
                newData = event.NewData;
                range = [49,59];
                if app.brest_t_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.brest_t_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.brest_t_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 60 && event.Indices(1) <= 68 && event.Indices(2) == 4
                newData = event.NewData;
                range = [60,68];
                if app.gomel_t_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.gomel_t_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.gomel_t_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            % ========================== p_night ================================================
            if event.Indices(1) >= 4 && event.Indices(1) <= 16 && event.Indices(2) == 6
                newData = event.NewData;
                range = [4,16];
                if app.vitebsk_p_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.vitebsk_p_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.vitebsk_p_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 17 && event.Indices(1) <= 25 && event.Indices(2) == 6
                newData = event.NewData;
                range = [17,25];
                if app.grodno_p_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.grodno_p_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.grodno_p_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 26 && event.Indices(1) <= 39 && event.Indices(2) == 6
                newData = event.NewData;
                range = [26,39];
                if app.minsk_p_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.minsk_p_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.minsk_p_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 40 && event.Indices(1) <= 48 && event.Indices(2) == 6
                newData = event.NewData;
                range = [40,48];
                if app.mogilev_p_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.mogilev_p_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.mogilev_p_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 49 && event.Indices(1) <= 59 && event.Indices(2) == 6
                newData = event.NewData;
                range = [49,59];
                if app.brest_p_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.brest_p_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.brest_p_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
            
            if event.Indices(1) >= 60 && event.Indices(1) <= 68 && event.Indices(2) == 6
                newData = event.NewData;
                range = [60,68];
                if app.gomel_p_d == 0
                    cellEdit_crutchd(app, range, event.Indices(2), newData);
                    app.gomel_p_d = 1;
                elseif isempty(newData)
                    cellEdit_crutchd(app, range, event.Indices(2),newData);
                    app.gomel_p_d = 0;
                else
                    %                     app.dataNight.Data{event.Indices(1),event.Indices(2)} = newData;
                end
            end
        end

        % Menu selected function: progtable_changemenu
        function progtable_changemenuMenuSelected(app, event)
            s = uiconfirm(app.Easy_Assessment, 'При смене расположения ячеек или их удалении, а также смене порядка листов программа будет работать некорректно. Редактировать можно только названия станций и явлений. Хотите продолжить?',...
                'Внимание!', 'Icon','info');
            if length(s) == 2
                if s =='OK'
                    app.editor_window = editProgtable(app);
                end
            else
                app.logPanel.Value = [app.logPanel.Value; 'Отмена операции'];
            end
        end

        % Menu selected function: progtable_restoremenu
        function progtable_restoremenuMenuSelected(app, event)
            copyfile('source\assessment_fullday.mat', pwd);
            copyfile('source\day_assessment.mat', pwd);
            copyfile('source\night_assessment.mat', pwd);
            copyfile('source\calc_table.mat', pwd);
            copyfile('source\calc_table_p.mat', pwd);
            copyfile('source\calc_table_phen.mat', pwd);
            copyfile('source\normData.mat', pwd);
            copyfile('source\territorial_assessment.mat', pwd);
            app.logPanel.Value = [app.logPanel.Value; 'Восстановление таблиц по умолчанию завершено'];
            uialert(app.Easy_Assessment, 'Перезапустите программу', 'Информация', 'Icon','info');
        end

        % Menu selected function: savePathmenu_restore
        function savePathmenu_restoreMenuSelected(app, event)
            copyfile('source\.cfg', 'bin\.cfg');
            app.logPanel.Value = [app.logPanel.Value; 'Путь по умолчанию сброшен'];
            
            try
                config_id = fopen([pwd, '\bin\.cfg'], 'r');
                app.defPath_pub = textscan(config_id, '%s');
                fclose(config_id);
                if ~isempty(app.defPath_pub{1})
                    temp = app.defPath_pub{1};
                    app.defPath_pub = temp{1};
                end
            catch exeption
                app.logPanel.Value = [app.logPanel.Value; 'Ошибка при открытии файла конфигурации'];
                app.logPanel.Value = [app.logPanel.Value; exeption.message];
            end
        end

        % Value changed function: menu_b
        function menu_bValueChanged(app, event)
            if app.menu_b.Value == 1
                app.Menu.Visible = 1;
            else
                app.Menu.Visible = 0;
            end
            
        end

        % Value changed function: fileDate_bar
        function fileDate_barValueChanged(app, event)
            date = app.fileDate_bar.Value;
            expression = '(?<day>\d+).(?<month>\d+).(?<year>\d+)';
            date = regexp(date, expression, 'names');
            try
                temp = [str2double(date(1).('day')), str2double(date(1).('month')), str2double(date(1).('year'))];
                if temp(1) < 10
                    temp_string = strcat('0', num2str(temp(1)));
                    if temp(2) < 10
                        temp_string = strcat(temp_string, '.0');
                        temp_string = strcat(temp_string, num2str(temp(2)));
                        temp_string = strcat(temp_string, '.');
                        temp_string = strcat(temp_string, num2str(temp(3)));
                    else
                        temp_string = strcat(temp_string, '.');
                        temp_string = strcat(temp_string, num2str(temp(2)));
                        temp_string = strcat(temp_string, '.');
                        temp_string = strcat(temp_string, num2str(temp(3)));
                    end
                else
                    temp_string = num2str(temp(1));
                    if temp(2) < 10
                        temp_string = strcat(temp_string, '.0');
                        temp_string = strcat(temp_string, num2str(temp(2)));
                        temp_string = strcat(temp_string, '.');
                        temp_string = strcat(temp_string, num2str(temp(3)));
                    else
                        temp_string = strcat(temp_string, '.');
                        temp_string = strcat(temp_string, num2str(temp(2)));
                        temp_string = strcat(temp_string, '.');
                        temp_string = strcat(temp_string, num2str(temp(3)));
                    end
                end
                app.fileDate_bar.Value = temp_string;
                if length(temp) == 3 && temp(1) <= 31 && temp(2) <= 12 && length(num2str(temp(3))) == 4
                    app.fileDate_pub = temp;
                else
                    app.fileDate_bar.Value = 'дд.мм.гггг';
                    uialert(app.Easy_Assessment, 'Введите дату в корректном формате', 'Ошибка', 'Icon','error');
                end
            catch
                app.fileDate_bar.Value = 'дд.мм.гггг';
                uialert(app.Easy_Assessment, 'Введите дату в корректном формате', 'Ошибка', 'Icon','error');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Easy_Assessment = uifigure;
            app.Easy_Assessment.Name = 'Easy assessment';
            app.Easy_Assessment.CloseRequestFcn = createCallbackFcn(app, @Easy_AssessmentCloseRequest, true);
            app.Menu = uimenu(app.Easy_Assessment);
            app.Menu.Visible = 'off';
            app.Menu.Text = 'Опции';
            app.savePathmenu = uimenu(app.Menu);
            app.savePathmenu.MenuSelectedFcn = createCallbackFcn(app, @b_savePathButtonPushed, true);
            app.savePathmenu.Text = 'Задать/изменить путь по умолчанию';
            app.savePathmenu_restore = uimenu(app.Menu);
            app.savePathmenu_restore.MenuSelectedFcn = createCallbackFcn(app, @savePathmenu_restoreMenuSelected, true);
            app.savePathmenu_restore.Text = 'Сбросить путь по умолчанию';
            app.progTableoptmenu = uimenu(app.Menu);
            app.progTableoptmenu.Text = 'Операции с таблицой по умолчанию';
            app.progtable_changemenu = uimenu(app.progTableoptmenu);
            app.progtable_changemenu.MenuSelectedFcn = createCallbackFcn(app, @progtable_changemenuMenuSelected, true);
            app.progtable_changemenu.Text = 'Изменить таблицу программы';
            app.progtable_restoremenu = uimenu(app.progTableoptmenu);
            app.progtable_restoremenu.MenuSelectedFcn = createCallbackFcn(app, @progtable_restoremenuMenuSelected, true);
            app.progtable_restoremenu.Text = 'Восстановить таблицу программы';
            app.excelMenu = uimenu(app.Menu);
            app.excelMenu.Text = 'Операции с входной таблицой excel';
            app.getExcelmenu = uimenu(app.excelMenu);
            app.getExcelmenu.MenuSelectedFcn = createCallbackFcn(app, @getExcelmenuMenuSelected, true);
            app.getExcelmenu.Text = 'Получить образец входного excel';
            app.changeExcelmenu = uimenu(app.excelMenu);
            app.changeExcelmenu.MenuSelectedFcn = createCallbackFcn(app, @changeExcelmenuMenuSelected, true);
            app.changeExcelmenu.Text = 'Изменить образец входного excel';
            app.restoreExcelmenu = uimenu(app.excelMenu);
            app.restoreExcelmenu.MenuSelectedFcn = createCallbackFcn(app, @restoreExcelmenuMenuSelected, true);
            app.restoreExcelmenu.Text = 'Восстановить образец excel';
            app.Exitmenu = uimenu(app.Menu);
            app.Exitmenu.MenuSelectedFcn = createCallbackFcn(app, @b_exitButtonPushed, true);
            app.Exitmenu.Text = 'Выход';
            app.b_calculate_input = uibutton(app.Easy_Assessment, 'push');
            app.b_calculate_input.ButtonPushedFcn = createCallbackFcn(app, @b_calculate_inputButtonPushed, true);
            app.b_calculate_input.Text = {'Расчитать по '; 'excel файлу'};
            app.logPanel = uitextarea(app.Easy_Assessment);
            app.Panel = uipanel(app.Easy_Assessment);
            app.Panel.Title = 'Доп. параметры';
            app.t_lim_prec = uieditfield(app.Panel, 'numeric');
            app.t_lim_prec.Limits = [-50 50];
            app.t_lim_prec.Value = -5;
            app.t_lim_prec_label = uilabel(app.Panel);
            app.t_lim_prec_label.Text = {'Пороговая t° '; 'для осадков'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Текущ. дата';
            app.fileDate_bar = uieditfield(app.Panel, 'text');
            app.fileDate_bar.ValueChangedFcn = createCallbackFcn(app, @fileDate_barValueChanged, true);
            app.fileDate_bar.Tooltip = {'Формат даты: дд.мм.гггг'};
            app.fileDate_bar.Value = 'дд.мм.гггг';
            app.AllTabs = uitabgroup(app.Easy_Assessment);
            app.tabAsNight = uitab(app.AllTabs);
            app.tabAsNight.Title = 'Оценка ночь';
            app.dataAssessNight = uitable(app.tabAsNight);
            app.dataAssessNight.ColumnName = {''};
            app.dataAssessNight.RowName = {};
            app.dataAssessNight.ColumnEditable = true;
            app.dataAssessNight.CellEditCallback = createCallbackFcn(app, @dataAssessNightCellEdit, true);
            app.tabAsDay = uitab(app.AllTabs);
            app.tabAsDay.Title = 'Оценка день';
            app.dataAssessDay = uitable(app.tabAsDay);
            app.dataAssessDay.ColumnName = '';
            app.dataAssessDay.RowName = {};
            app.dataAssessDay.ColumnEditable = true;
            app.dataAssessDay.CellEditCallback = createCallbackFcn(app, @dataAssessDayCellEdit, true);
            app.tabFullday = uitab(app.AllTabs);
            app.tabFullday.Title = 'Сутки';
            app.dataFullDay = uitable(app.tabFullday);
            app.dataFullDay.ColumnName = '';
            app.dataFullDay.RowName = {};
            app.dataFullDay.ColumnEditable = false;
            app.tabTerritory = uitab(app.AllTabs);
            app.tabTerritory.Title = 'Территория';
            app.dataTerritory = uitable(app.tabTerritory);
            app.dataTerritory.ColumnName = '';
            app.dataTerritory.RowName = {};
            app.dataTerritory.ColumnEditable = false;
            app.tabCalc = uitab(app.AllTabs);
            app.tabCalc.Title = 'Доп.оценка температур';
            app.dataCalc = uitable(app.tabCalc);
            app.dataCalc.ColumnName = '';
            app.dataCalc.RowName = {};
            app.dataCalc.ColumnEditable = false;
            app.tabCalc_p = uitab(app.AllTabs);
            app.tabCalc_p.Title = 'Доп.оценка осадков';
            app.dataCalc_p = uitable(app.tabCalc_p);
            app.dataCalc_p.ColumnName = '';
            app.dataCalc_p.RowName = {};
            app.dataCalc_p.ColumnEditable = false;
            app.tabCalc_phen = uitab(app.AllTabs);
            app.tabCalc_phen.Title = 'Доп. оценка явлений';
            app.dataCalc_phen = uitable(app.tabCalc_phen);
            app.dataCalc_phen.ColumnName = '';
            app.dataCalc_phen.RowName = {};
            app.dataCalc_phen.ColumnEditable = false;
            app.b_calculate_inprog = uibutton(app.Easy_Assessment, 'push');
            app.b_calculate_inprog.ButtonPushedFcn = createCallbackFcn(app, @b_calculate_inprogButtonPushed, true);
            app.b_calculate_inprog.Text = {'Расчитать по '; 'таблице программы'};
            app.b_save = uibutton(app.Easy_Assessment, 'push');
            app.b_save.ButtonPushedFcn = createCallbackFcn(app, @b_saveButtonPushed, true);
            app.b_save.Enable = 'off';
            app.b_save.Text = {'Сохранить '; 'результат'};
            app.b_clear = uibutton(app.Easy_Assessment, 'push');
            app.b_clear.ButtonPushedFcn = createCallbackFcn(app, @b_clearButtonPushed, true);
            app.b_clear.Text = {'Очистить'; ' таблицу'};
            app.menu_b = uibutton(app.Easy_Assessment, 'state');
            app.menu_b.ValueChangedFcn = createCallbackFcn(app, @menu_bValueChanged, true);
            app.menu_b.Text = {'Вкл. панель'; ' опций'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Easy_Assessment)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Easy_Assessment)
        end
    end
end